classdef dialogbox_3Dplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        axis1DropDownLabel  matlab.ui.control.Label
        axis1DropDown       matlab.ui.control.DropDown
        axis2DropDownLabel  matlab.ui.control.Label
        axis2DropDown       matlab.ui.control.DropDown
        axis3DropDownLabel  matlab.ui.control.Label
        axis3DropDown       matlab.ui.control.DropDown
        PlotButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainapp % calling app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.UIAxes.Title.Color = [0.80,0.80,0.80];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            axis = {app.axis1DropDown.Value,app.axis2DropDown.Value,app.axis3DropDown.Value};
            
            if ~strcmpi(axis{3}, 'none')
                data = zeros(size(app.mainapp.SpikeMat,1),3);
                if contains(join([axis{1}, axis{2}, axis{3}]), 'PCA')
                    [~, spike_pca] = pca(app.mainapp.SpikeMat,'NumComponents',3);
                    
                end
                [max_val,argmax] = max(app.mainapp.SpikeMat,[],2);
                [min_val,argmin] = min(app.mainapp.SpikeMat,[],2);
                for i = 1:3
                    switch axis{i}
                        case 'PCA1'
                            data(:,i) = spike_pca(:,1);
                        case 'PCA2'
                            data(:,i) = spike_pca(:,2);
                        case 'PCA3'
                            data(:,i) = spike_pca(:,3);
                        case 'time'
                            data(:,i) = app.mainapp.SpikeTime;
                        case 'p2p'
                            data(:,i) = max_val - min_val;
                        case 'duty'
                            data(:,i) = argmax - argmin;
                    end
                end
                
                
                cla(app.UIAxes)
                hold(app.UIAxes, 'on')
                classes = unique(app.mainapp.os.cluster_index);
                
                for i = 1 : length(classes)
                    cluster_spikes = data(app.mainapp.os.cluster_index == classes(i),:);
                    if size(cluster_spikes,1) > 1000
                        ii = randperm(size(cluster_spikes,1),1000);
                        cluster_spikes = cluster_spikes(ii,:);
                    end
                    plot3(app.UIAxes, cluster_spikes(:,1),cluster_spikes(:,2),cluster_spikes(:,3),'*','DisplayName',...
                        sprintf('C = %d',classes(i)),'Color',app.mainapp.color(i,:));
                end
                rotate3d(app.UIAxes,'on');
                xlabel(app.UIAxes, axis{1});
                ylabel(app.UIAxes, axis{2});
                zlabel(app.UIAxes, axis{3});
         
            else
                data = zeros(size(app.mainapp.SpikeMat,1),2);
                if contains(join([axis{1}, axis{2}]), 'PCA')
                    [~, spike_pca] = pca(app.mainapp.SpikeMat,'NumComponents',3);
                    
                end
                [max_val,argmax] = max(app.mainapp.SpikeMat,[],2);
                [min_val,argmin] = min(app.mainapp.SpikeMat,[],2);
                
                for i = 1:2
                    switch axis{i}
                        case 'PCA1'
                            data(:,i) = spike_pca(:,1);
                        case 'PCA2'
                            data(:,i) = spike_pca(:,2);
                        case 'PCA3'
                            data(:,i) = spike_pca(:,3);
                        case 'time'
                            data(:,i) = app.mainapp.SpikeTime;
                        case 'p2p'
                            data(:,i) = max_val - min_val;
                        case 'duty'
                            data(:,i) = argmax - argmin;
                    end
                end
                
                cla(app.UIAxes)
                hold(app.UIAxes, 'on')
                classes = unique(app.mainapp.os.cluster_index);
                
                for i = 1 : length(classes)
                    cluster_spikes = data(app.mainapp.os.cluster_index == classes(i),:);
                    if size(cluster_spikes,1) > 1000
                        ii = randperm(size(cluster_spikes,1),1000);
                        cluster_spikes = cluster_spikes(ii,:);
                    end
                    plot(app.UIAxes, cluster_spikes(:,1),cluster_spikes(:,2),'*','DisplayName',...
                        sprintf('C = %d',classes(i)),'Color',app.mainapp.color(i,:));
                    
                end
                rotate3d(app.UIAxes,'off');
                xlabel(app.UIAxes, axis{1});
                ylabel(app.UIAxes, axis{2});

                %                 Toolbar(app.UIAxes, 'on')
            end
            grid(app.UIAxes, 'on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '3D Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            app.axis1DropDownLabel = uilabel(app.UIFigure);
            app.axis1DropDownLabel.Text = 'axis 1';
            app.axis1DropDown = uidropdown(app.UIFigure);
            app.axis1DropDown.Items = {'PCA1', 'PCA2', 'PCA3', 'time', 'p2p', 'duty'};
            app.axis1DropDown.Value = 'PCA1';
            app.axis2DropDownLabel = uilabel(app.UIFigure);
            app.axis2DropDownLabel.Text = 'axis 2';
            app.axis2DropDown = uidropdown(app.UIFigure);
            app.axis2DropDown.Items = {'PCA1', 'PCA2', 'PCA3', 'time', 'p2p', 'duty'};
            app.axis2DropDown.Value = 'PCA2';
            app.axis3DropDownLabel = uilabel(app.UIFigure);
            app.axis3DropDownLabel.Text = 'axis 3';
            app.axis3DropDown = uidropdown(app.UIFigure);
            app.axis3DropDown.Items = {'PCA1', 'PCA2', 'PCA3', 'time', 'p2p', 'duty', 'none'};
            app.axis3DropDown.Value = 'PCA3';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogbox_3Dplot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end