classdef dialogbox_AssignToNearest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SourceListBoxLabel  matlab.ui.control.Label
        SourceListBox       matlab.ui.control.ListBox
        TargetListBoxLabel  matlab.ui.control.Label
        TargetListBox       matlab.ui.control.ListBox
        ApplyButton         matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        Label               matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.SourceListBox.Items = string(unique(mainapp.os.cluster_index));
            app.TargetListBox.Items = string(unique(mainapp.os.cluster_index));
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            source = app.SourceListBox.Value;
            target = app.TargetListBox.Value;
            if ~isempty(source) && ~isempty(target)
                app.mainapp.back_history(2:5,:) = app.mainapp.back_history(1:4,:);
                app.mainapp.back_history{1,1} = 'Assign to nearest';
                app.mainapp.back_history{1,2} = app.mainapp.os.cluster_index;
                
                source = cellfun(@str2double, source);
                target_vec = cellfun(@str2double, target);
                
                % finding indices of spikes of the source cluster
                indx_source = ismember(app.mainapp.os.cluster_index , source);
                
                dist = zeros(length(app.mainapp.os.cluster_index),length(target_vec)) - 1;
                
                % calculating distance from source cluster spikes to each
                % of the target clusters average
                for i = 1 : size(dist,2)
                    dist(indx_source,i) = pdist2(app.mainapp.SpikeMat(indx_source,:),mean(app.mainapp.SpikeMat(app.mainapp.os.cluster_index==target_vec(i),:)));
                end
                
                % finding nearest
                [~,nearest_target] = min(dist,[],2);
                nearest_target = nearest_target .* indx_source - size(dist,2);
                
                % assigning spikes from source to nearest target
                for i = 1 : size(dist,2)
                    app.mainapp.os.cluster_index(nearest_target == i - size(dist,2)) = target_vec(i);
                end
                
                app.mainapp.log = [join([num2str(source),' assigned to ', num2str(target_vec)]), app.mainapp.log];
                app.mainapp.logListBox.Items = app.mainapp.log;
                app.mainapp.os.cluster_index = cluster_index_cleaner(app.mainapp.os.cluster_index);
                app.mainapp.ClustersindexListBox.Items = string(unique(app.mainapp.os.cluster_index));
                
                app.SourceListBox.Items = string(unique(app.mainapp.os.cluster_index));
                app.TargetListBox.Items = string(unique(app.mainapp.os.cluster_index));
                
                drawnow
                
                app.mainapp.update_figure();
                
            else
                magbox('You should at least a source and a target cluster.','Assign Error','Error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Assign to nearest';
            app.SourceListBoxLabel = uilabel(app.UIFigure);
            app.SourceListBoxLabel.Text = {'Source '; ''};
            app.SourceListBox = uilistbox(app.UIFigure);
            app.SourceListBox.Multiselect = 'on';
            app.SourceListBox.Value = {'Item 1'};
            app.TargetListBoxLabel = uilabel(app.UIFigure);
            app.TargetListBoxLabel.Text = 'Target';
            app.TargetListBox = uilistbox(app.UIFigure);
            app.TargetListBox.Multiselect = 'on';
            app.TargetListBox.Value = {'Item 1'};
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Choose source and target clusters to assign samples of the source clusters to the'; 'nearest target clusters. You can choose multiple clusters usig CTRL or Shift.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogbox_AssignToNearest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end