classdef dialogbox_denoise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DenoisingUIFigure              matlab.ui.Figure
        DenoisingThresholdSliderLabel  matlab.ui.control.Label
        DenoisingThresholdSlider       matlab.ui.control.Slider
        DataPlotPercentageSliderLabel  matlab.ui.control.Label
        DataPlotPercentageSlider       matlab.ui.control.Slider
        waveUIAxes                     matlab.ui.control.UIAxes
        noiseUIAxes                    matlab.ui.control.UIAxes
        PlotButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ClusterIndexDropDownLabel      matlab.ui.control.Label
        ClusterIndexDropDown           matlab.ui.control.DropDown
        ProcessingLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp % calling app
        REM = []; %noise index
        dist= [];
        SpikeMean = []
        ind_this_cluster = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            classes = unique(app.mainapp.os.cluster_index);
            app.ClusterIndexDropDown.Items = string(classes(classes < 200));
            app.noiseUIAxes.Title.Color = [0.80,0.80,0.80];
            app.waveUIAxes.Title.Color = [0.80,0.80,0.80];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.PlotButton.Enable = false;
            app.SaveButton.Enable = false;
            app.ProcessingLabel.Text = 'Processing...';
            drawnow;
          
            app.waveUIAxes.Title.String = sprintf('%2.0f%% of data Waveform',app.DataPlotPercentageSlider.Value);
            rng(561371)
            app.ind_this_cluster = app.mainapp.os.cluster_index == str2double(app.ClusterIndexDropDown.Value);
            spikemat = app.mainapp.SpikeMat(app.ind_this_cluster,:);
            
            % pca feature extraction using 5 components
            [~,SpikeMatPCA] = pca(spikemat,'numcomponents',5);
            
            % calculating distances from mean and normalizing distances
            app.SpikeMean = mean(SpikeMatPCA);
            app.dist = pdist2(SpikeMatPCA,app.SpikeMean);
            app.dist = app.dist./max(app.dist);
            
            
            p = app.DataPlotPercentageSlider.Value/100;
            thr = app.DenoisingThresholdSlider.Value/100;
            
            % comparing distances to threshold
            app.REM = app.dist > thr;
            spikemat_not_removed = app.mainapp.SpikeMat(~app.REM,:);
            
            ns = floor(size(spikemat_not_removed,1)*p);
            ind_spikes_show = randperm(size(spikemat_not_removed,1),ns);
            plot(app.waveUIAxes,spikemat_not_removed(ind_spikes_show,:)')
            plot(app.noiseUIAxes,spikemat(app.REM,:)')
            xlabel(app.noiseUIAxes, sprintf('%d spikes are removed',sum(app.REM)))
            app.noiseUIAxes.XLim =  app.waveUIAxes.XLim;
            app.noiseUIAxes.YLim =  app.waveUIAxes.YLim;
            app.PlotButton.Enable = true;
            app.SaveButton.Enable = true;
            app.ProcessingLabel.Text = ' ';
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            if ~isempty(app.REM)
                
                app.mainapp.back_history(2:5,:) = app.mainapp.back_history(1:4,:);
                app.mainapp.back_history{1,1} = 'Denoise';
                app.mainapp.back_history{1,2} = app.mainapp.os.cluster_index;
                
                tmp = find(app.ind_this_cluster);
                ind_cluster = app.mainapp.os.cluster_index(tmp(1));
                tmp(~app.REM) = [];
                
                % move outlier spikes to cluster 255
                app.mainapp.os.cluster_index(tmp) = 255;
                msgbox('Saved successfully.', 'Saved')
                app.mainapp.update_figure();
                
                app.mainapp.log = [sprintf('%d denoised.', ind_cluster), app.mainapp.log];
                app.mainapp.logListBox.Items = app.mainapp.log;
                drawnow
                
                app.mainapp.ClustersindexListBox.Items = string(unique(app.mainapp.os.cluster_index));
            else
                msgbox('Nothing to save.', 'Error','error')
            end
            
            app.delete
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DenoisingUIFigure = uifigure('Visible', 'off');
            app.DenoisingUIFigure.Name = 'Denoising';
            app.DenoisingThresholdSliderLabel = uilabel(app.DenoisingUIFigure);
            app.DenoisingThresholdSliderLabel.Text = {'Denoising'; 'Threshold'};
            app.DenoisingThresholdSlider = uislider(app.DenoisingUIFigure);
            app.DenoisingThresholdSlider.Value = 80;
            app.DataPlotPercentageSliderLabel = uilabel(app.DenoisingUIFigure);
            app.DataPlotPercentageSliderLabel.Text = {'Data Plot'; 'Percentage'};
            app.DataPlotPercentageSlider = uislider(app.DenoisingUIFigure);
            app.DataPlotPercentageSlider.Value = 1;
            app.waveUIAxes = uiaxes(app.DenoisingUIFigure);
            title(app.waveUIAxes, 'Waveform')
            xlabel(app.waveUIAxes, 'Time')
            app.noiseUIAxes = uiaxes(app.DenoisingUIFigure);
            title(app.noiseUIAxes, 'Noise')
            xlabel(app.noiseUIAxes, 'Time')
            app.PlotButton = uibutton(app.DenoisingUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.DenoisingUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save the removed samples as noise cluster.'};
            app.SaveButton.Text = 'Save';
            app.ClusterIndexDropDownLabel = uilabel(app.DenoisingUIFigure);
            app.ClusterIndexDropDownLabel.Text = 'Cluster Index';
            app.ClusterIndexDropDown = uidropdown(app.DenoisingUIFigure);
            app.ProcessingLabel = uilabel(app.DenoisingUIFigure);
            app.ProcessingLabel.Text = '';
            % Show the figure after all components are created
            app.DenoisingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogbox_denoise(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DenoisingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DenoisingUIFigure)
        end
    end
end