classdef dialogbox_dataRaw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        OKButton              matlab.ui.control.Button
        CancelButton          matlab.ui.control.Button
        RawDataDropDownLabel  matlab.ui.control.Label
        RawDataDropDown       matlab.ui.control.DropDown
        ChoosetherawdatafromthematfileRawdatamustbea1NarrayLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp
        data_str
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, variable_names, data_str)
            app.RawDataDropDown.Items = variable_names;
            app.mainapp = mainapp;
            app.data_str = data_str;
            
        end

        % Value changed function: RawDataDropDown
        function RawDataDropDownValueChanged(app, event)
            value = app.RawDataDropDown.Value;
            
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
%             % Enable the Plot Opions button in main app
%             app.CallingApp.OptionsButton.Enable = 'on';
%             
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainapp.raw_data_name = app.RawDataDropDown.Value;
            
            raw_data = getfield(app.data_str, app.RawDataDropDown.Value);
            if isvector(raw_data)
                app.mainapp.raw_data = raw_data;
            else
%                 uialert(app.UIFigure,'Raw Data must be a vector!', 'ERROR')                
                msgbox('Raw Data must be a vector!')                

            end
            delete(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Data Raw';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RawDataDropDownLabel = uilabel(app.UIFigure);
            app.RawDataDropDownLabel.Text = 'Raw Data';
            app.RawDataDropDown = uidropdown(app.UIFigure);
            app.RawDataDropDown.ValueChangedFcn = createCallbackFcn(app, @RawDataDropDownValueChanged, true);
            app.ChoosetherawdatafromthematfileRawdatamustbea1NarrayLabel = uilabel(app.UIFigure);
            app.ChoosetherawdatafromthematfileRawdatamustbea1NarrayLabel.Text = {'Choose the raw data from the mat-file. Raw data'; 'must be a 1×N array.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = dialogbox_dataRaw(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end