classdef dialogbox_tag < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UITable       matlab.ui.control.Table
        CancelButton  matlab.ui.control.Button
        OkButton      matlab.ui.control.Button
        Label         matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            if isempty(app.mainapp.tag)
                type = cell(length(unique(mainapp.os.cluster_index)),1);
                type(:) = {'Single Unit'};
                cnames = categorical(type,{'Single Unit', 'Multi Unit'});
                other = cell(length(unique(mainapp.os.cluster_index)),1);
                other(:) = {' '};
                app.UITable.Data = table(string(unique(mainapp.os.cluster_index)),cnames,other,'VariableNames',{'Cluster_Index','Type', 'Other'});
            else
                app.UITable.Data = app.mainapp.tag;
            end
            app.UITable.ColumnEditable = true;
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.mainapp.tag = app.UITable.Data;
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Tag Clusters';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Cluster Index'; 'Type'; 'Other'};
            app.UITable.RowName = {};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Assign tags to neurons. By choosing the cluster index you can determine '; 'neuron type and assign an arbitrary tag.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = dialogbox_tag(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end