classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        RawDataMenu                     matlab.ui.container.Menu
        DetectedDataMenu                matlab.ui.container.Menu
        SortedDataMenu                  matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        saveDetectionResultMenu         matlab.ui.container.Menu
        saveSortingResultsMenu          matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        VisualizationMenu               matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        LiveTimeMenu                    matlab.ui.container.Menu
        ISIMenu                         matlab.ui.container.Menu
        DPlotMenu                       matlab.ui.container.Menu
        RawDataMenu_plot                matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DetectionTab                    matlab.ui.container.Tab
        StartDetectionButton            matlab.ui.control.Button
        ThresholdMethodDropDownLabel    matlab.ui.control.Label
        ThresholdMethodDropDown         matlab.ui.control.DropDown
        SignalSideThresholdingDropDownLabel  matlab.ui.control.Label
        SignalSideThresholdingDropDown  matlab.ui.control.DropDown
        SpikerFilterTypeDropDownLabel   matlab.ui.control.Label
        SpikeFilterTypeDropDown         matlab.ui.control.DropDown
        FilterOrderSpinnerLabel         matlab.ui.control.Label
        FilterOrderSpinner              matlab.ui.control.Spinner
        PassFreqHzLabel                 matlab.ui.control.Label
        PassFreqHzEditField             matlab.ui.control.NumericEditField
        StopFreqHzLabel                 matlab.ui.control.Label
        StopFreqHzEditField             matlab.ui.control.NumericEditField
        PreThresholdsamplesLabel        matlab.ui.control.Label
        PreThresholdsamplesEditField    matlab.ui.control.NumericEditField
        PostThresholdsamplesLabel       matlab.ui.control.Label
        PostThresholdsamplesEditField   matlab.ui.control.NumericEditField
        DeadtimesamplesLabel            matlab.ui.control.Label
        DeadtimesamplesEditField        matlab.ui.control.NumericEditField
        SetasDefaultSettingButtonDetection  matlab.ui.control.Button
        SamplingRateLabel               matlab.ui.control.Label
        SamplingRateSpinner             matlab.ui.control.Spinner
        AutoSortingTab                  matlab.ui.container.Tab
        AlignmentLabel                  matlab.ui.control.Label
        tDistributionSortingLabel       matlab.ui.control.Label
        StatisticalFilteringLabel       matlab.ui.control.Label
        MaxShiftSpinnerLabel            matlab.ui.control.Label
        MaxShiftSpinner                 matlab.ui.control.Spinner
        NumberofPeaksLabel              matlab.ui.control.Label
        NumberofPeaksSpinner            matlab.ui.control.Spinner
        HistogrambinsSpinnerLabel       matlab.ui.control.Label
        HistogrambinsSpinner            matlab.ui.control.Spinner
        ComparisonmodeLabel             matlab.ui.control.Label
        ComparisonmodeDropDown          matlab.ui.control.DropDown
        MaxstdEditFieldLabel            matlab.ui.control.Label
        MaxstdEditField                 matlab.ui.control.NumericEditField
        MaxmeanEditFieldLabel           matlab.ui.control.Label
        MaxmeanEditField                matlab.ui.control.NumericEditField
        MaxpercentofoutliersLabel       matlab.ui.control.Label
        MaxpercentofoutliersEditField   matlab.ui.control.NumericEditField
        nuEditFieldLabel                matlab.ui.control.Label
        nuEditField                     matlab.ui.control.NumericEditField
        gmaxSpinnerLabel                matlab.ui.control.Label
        gmaxSpinner                     matlab.ui.control.Spinner
        gminSpinnerLabel                matlab.ui.control.Label
        gminSpinner                     matlab.ui.control.Spinner
        MaxiterationSpinnerLabel        matlab.ui.control.Label
        MaxiterationSpinner             matlab.ui.control.Spinner
        ulimEditFieldLabel              matlab.ui.control.Label
        ulimEditField                   matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        StatisticalFilteringCheckBox    matlab.ui.control.CheckBox
        AlignmentCheckBox               matlab.ui.control.CheckBox
        PCAnumSpinnerLabel              matlab.ui.control.Label
        PCAnumSpinner                   matlab.ui.control.Spinner
        RandomseedEditFieldLabel        matlab.ui.control.Label
        RandomseedEditField             matlab.ui.control.NumericEditField
        SetasdefaultsettingButtonSort   matlab.ui.control.Button
        SortButton                      matlab.ui.control.Button
        RunLabel                        matlab.ui.control.Label
        AlignmentLabel_2                matlab.ui.control.Label
        SortingTypeLabel                matlab.ui.control.Label
        SortingType                     matlab.ui.control.DropDown
        ManualSortingTab                matlab.ui.container.Tab
        ClustersindexListBoxLabel       matlab.ui.control.Label
        ClustersindexListBox            matlab.ui.control.ListBox
        MergeButton                     matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        ResortButton                    matlab.ui.control.Button
        DenoiseButton                   matlab.ui.control.Button
        AssigntonearestButton           matlab.ui.control.Button
        TagButton                       matlab.ui.control.Button
        PCAremoveButton                 matlab.ui.control.Button
        PCAgroupButton                  matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        UIAxes_hist                     matlab.ui.control.UIAxes
        UIAxes_pca                      matlab.ui.control.UIAxes
        UIAxes_waveform                 matlab.ui.control.UIAxes
        logListBoxLabel                 matlab.ui.control.Label
        logListBox                      matlab.ui.control.ListBox
        UIAxes_legend                   matlab.ui.control.UIAxes
        CurrentFileLabel                matlab.ui.control.Label
        CurrentFileResultLabel          matlab.ui.control.Label
        UpdateFiguresButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        DialogApp_dataraw % raw data selecting
        DialogApp_assign %assign to nearest
        DialogApp_tag %tag clusters
        DialogApp_3Dplot % ploting a 3D-plot with arbitary axes
        DialogApp_denoise % denosing data
        s_detection % detection setting struct
        s_ASorting % automatic sorting setting struct
        
    end
    
    properties (Access = public)
        color = distinguishable_colors(200, 'w');
        raw_data_name = [] % Description
        raw_data = []
        os % automatic sortin results
        SpikeMat
        SpikeTime
        tag % neuron tags
        log % log history
        back_history = cell(5,2);
    end
    
    methods (Access = private)


        function load_raw(app)
            [filename, path] = uigetfile('*.mat');
            drawnow; figure(app.UIFigure)
            loaded = 'Nothing';
            if ~isequal(filename,0) || ~isequal(path,0)
                
                cla(app.UIAxes_waveform)
                cla(app.UIAxes_hist)
                cla(app.UIAxes_legend)
                cla(app.UIAxes_pca)
                
                app.raw_data = [];
                app.raw_data_name = [];
                app.SpikeMat = [];
                app.SpikeTime = [];
                app.os = [];
                
                data_str = load(join([path,filename]));
                variable_names = fieldnames(data_str);
                if  length(variable_names) >= 1
                    app.DialogApp_dataraw = dialogbox_dataRaw(app,variable_names, data_str);
                    loaded = filename;
                    %             elseif length(variable_names) == 1
                    %                 app.raw_data_name = variable_names{1};
                    uiwait(app.DialogApp_dataraw.UIFigure)
                    app.CurrentFileResultLabel.Text = join([ filename, ' > ', app.raw_data_name]);
                    app.CurrentFileResultLabel.FontColor = [0.90,0.90,0.90];
                else
                    msgbox('Your mat-file is empty!', 'Empty mat-file','error')
                end
            end
            
            app.log = [join(['"', loaded, '" is loaded.']), app.log];
            app.logListBox.Items = app.log;
            
            app.RawDataMenu_plot.Enable = true;
            
            if ~isequal(loaded,'Nothing')
                app.log = [join([app.raw_data_name, ' with ', char(string(length(app.raw_data))), ' samples is selected']), app.log];
                app.logListBox.Items = app.log;
                
                app.MSort_enable(false);
                app.saveDetectionResultMenu.Enable = false;
                app.saveSortingResultsMenu.Enable = false;
            end
        end
        
        
        function load_detected(app)
            [filename,path] = uigetfile('*.mat');
            drawnow; figure(app.UIFigure)
            loaded = 'Nothing';
            if filename ~= 0
                loaded = filename;
                data_str = load(fullfile(path,filename));
                if ~isfield(data_str,'SpikeMat')
                    msgbox('SpikeMat is missing!','Detected Data Error','error')
                    loaded = 'Nothing';
                end
                if ~isfield(data_str,'SpikeTime')
                    msgbox('SpikeTime is missing!','Detected Data Error','error')
                    loaded = 'Nothing';
                end
                if ~ismatrix(data_str.SpikeMat)
                    msgbox('SpikeMat must be a matrix!','Detected Data Error','error')
                    loaded = 'Nothing';
                end
                if ~isvector(data_str.SpikeTime)
                    msgbox('SpikeTime must be a vector!','Detected Data Error','error')
                    loaded = 'Nothing';
                end
            end
            
            if ~strcmpi(loaded,'Nothing')
                cla(app.UIAxes_waveform)
                cla(app.UIAxes_hist)
                cla(app.UIAxes_legend)
                cla(app.UIAxes_pca)
                
                app.raw_data = [];
                app.raw_data_name = [];
                app.SpikeMat = [];
                app.SpikeTime = [];
                app.os = [];
                app.RawDataMenu_plot.Enable = false;
                
                app.MSort_enable(false);
                app.SpikeMat = data_str.SpikeMat;
                app.SpikeTime = data_str.SpikeTime;
                app.update_histogram();
                app.saveSortingResultsMenu.Enable = false;
                
                app.CurrentFileResultLabel.Text =  filename;
                app.CurrentFileResultLabel.FontColor = [0.90,0.90,0.90];
            end
            
            app.log = [join(['"', loaded, '" is loaded.']), app.log];
            app.logListBox.Items = app.log;
        end


        function load_sorted(app)
            [filename,path] = uigetfile('*.mat');
            drawnow; figure(app.UIFigure)
            loaded = 'Nothing';
            if filename ~= 0
                loaded = filename;
                data_str = load(fullfile(path,filename));
                if ~isfield(data_str,'SpikeMat')
                    msgbox('SpikeMat is missing!','Detected Data Error','error')
                    loaded = 'Nothing';
                end
                if ~isfield(data_str,'SpikeTime')
                    msgbox('SpikeTime is missing!','Detected Data Error','error')
                    loaded = 'Nothing';
                end
                if ~isfield(data_str,'optimal_set')
                    msgbox('optimal_set is missing!','Detected Data Error','error')
                    loaded = 'Nothing';
                else
                    if ~isfield(data_str.optimal_set,'cluster_index')
                        msgbox('cluster_index is missing!','Detected Data Error','error')
                        loaded = 'Nothing';
                    end
                end
                if ~ismatrix(data_str.SpikeMat)
                    msgbox('SpikeMat must be a matrix!','Detected Data Error','error')
                    loaded = 'Nothing';
                end
                if ~isvector(data_str.SpikeTime)
                    msgbox('SpikeTime must be a vector!','Detected Data Error','error')
                    loaded = 'Nothing';
                end
                
            end
            
            if ~strcmpi(loaded,'Nothing')
                cla(app.UIAxes_waveform)
                cla(app.UIAxes_hist)
                cla(app.UIAxes_legend)
                cla(app.UIAxes_pca)
                
                app.raw_data = [];
                app.raw_data_name = [];
                app.SpikeMat = [];
                app.SpikeTime = [];
                app.os = [];
                app.RawDataMenu_plot.Enable = false;
                app.SpikeMat = data_str.SpikeMat;
                app.SpikeTime = data_str.SpikeTime;
                app.os = data_str.optimal_set;
                app.update_histogram();
                app.update_figure();
                app.MSort_enable(true);
                
                app.CurrentFileResultLabel.Text =  filename;
                app.CurrentFileResultLabel.FontColor = [0.90,0.90,0.90];
                
            end
            
            app.log = [join(['"',loaded, '" is loaded.']), app.log];
            app.logListBox.Items = app.log;
            
        end


        function update_histogram(app)
            if ~isempty(app.SpikeMat)
                [~,spike_pca] = pca(app.SpikeMat, 'NumComponents',2);
                histogram2(app.UIAxes_hist, spike_pca(:,1), spike_pca(:,2), [100, 100], 'FaceColor','flat')
                grid(app.UIAxes_hist,'on'), view(app.UIAxes_hist, 2)
            end
        end


        function MSort_enable(app,enable) % enable or disable manual soring tab
            if enable
                app.ClustersindexListBox.Items = string(unique(app.os.cluster_index));
            else
                app.ClustersindexListBox.Items = string([]);
            end
            app.MergeButton.Enable = enable;
            app.RemoveButton.Enable = enable;
            app.ResortButton.Enable = enable;
            app.DenoiseButton.Enable = enable;
            app.AssigntonearestButton.Enable = enable;
            app.TagButton.Enable = enable;
            app.PCAremoveButton.Enable = enable;
            app.PCAgroupButton.Enable = enable;
            app.BackButton.Enable = enable;
            app.saveSortingResultsMenu.Enable = enable;
            
            %             app.back_history{1,1} = 'start';
            %             app.back_history{1,2} = {app.SpikeMat, app.SpikeTime, app.os.cluster_index};
            
            app.PlotMenu.Enable = enable;
            app.LiveTimeMenu.Enable = enable;
            app.ISIMenu.Enable = enable;
            app.DPlotMenu.Enable = enable;
            
        end
    end


    methods (Access = public)
        
        function update_figure(app)
            app.log = ['Updating figures. Please wait.', app.log];
            app.logListBox.Items = app.log;
            drawnow
            app.log(1) = [];
            % cluster
            
            cla(app.UIAxes_waveform)
            hold(app.UIAxes_waveform, 'on')
            
            try
                
                time_duration = app.SpikeTime(end) - app.SpikeTime(1);
                classes = unique(app.os.cluster_index);
                for j = 1 : ceil(length(classes)/3)
                    for i = 1 : 3
                        if (j-1)*3+i > length(classes)
                            break
                        end
                        cluster_spikes = app.SpikeMat(app.os.cluster_index == classes((j-1)*3+i),:);
                        
                        n = 200;
                        if length(classes) > 10
                            n = 100;
                        end
                        if size(cluster_spikes,1) > n
                            ii = randperm(size(cluster_spikes,1),n);
                            cluster_spikes = cluster_spikes(ii,:);
                        end
                        
                        plot(app.UIAxes_waveform,cluster_spikes','Color',[app.color((j-1)*3+i,:),0.05],...
                            'LineWidth',2)
                        ylim(app.UIAxes_waveform,[-5,5])
                    end
                end
                
                for j = 1 : ceil(length(classes)/3)
                    for i = 1 : 3
                        if (j-1)*3+i > length(classes)
                            break
                        end
                        cluster_spikes = app.SpikeMat(app.os.cluster_index == classes((j-1)*3+i),:);
                        mean_spike = mean(cluster_spikes);
                        n_cluster = sum(app.os.cluster_index == classes((j-1)*3+i));
                        display_name = sprintf('C_i = %d, PoP=%.1f, FR=%.2f',...
                            classes((j-1)*3+i),n_cluster./length(app.os.cluster_index)*100,...
                            n_cluster/time_duration*40000);
                        h((j-1)*3+i) = plot(app.UIAxes_waveform,mean_spike,'LineWidth',3,'Color',app.color((j-1)*3+i,:),...
                            'displayname',display_name);
                        ylim(app.UIAxes_waveform,[-5,5])
                    end
                end
                legend(app.UIAxes_legend,h, 'Location', 'best')
                legend(app.UIAxes_legend,'boxoff')
                
                alphamap(app.UIAxes_waveform,0.1)
            catch
                app.log = ['Waveform ploting is failed.', app.log];
                app.logListBox.Items = app.log;
                drawnow
            end
            
            try
                
                cla(app.UIAxes_pca)
                hold(app.UIAxes_pca, 'on')
                classes = unique(app.os.cluster_index);
                [~,Mat] = pca(app.SpikeMat,'numcomponents',2);
                for i = 1 : length(classes)
                    cluster_spikes = Mat(app.os.cluster_index == classes(i),:);
                    if size(cluster_spikes,1) > 500
                        ii = randperm(size(cluster_spikes,1),500);
                        cluster_spikes = cluster_spikes(ii,:);
                    end
                    plot(app.UIAxes_pca, cluster_spikes(:,1),cluster_spikes(:,2),'*','DisplayName',...
                        sprintf('C = %d',classes(i)),'Color',app.color(i,:));
                end
            catch
                app.log = ['PCA ploting is failed.', app.log];
                app.logListBox.Items = app.log;
                drawnow
            end
            
            app.UIAxes_pca.XLim =  app.UIAxes_hist.XLim;
            app.UIAxes_pca.YLim =  app.UIAxes_hist.YLim;
            
            grid(app.UIAxes_pca, 'on')
            
            %             set(app.UIAxes_pca,'Xtick',[])
            %             set(app.UIAxes_pca,'Ytick',[])
            %             set(app.UIAxes_pca,'Ztick',[])
            %             xlabel(app.UIAxes_pca, 'PCA-1')
            %             ylabel(app.UIAxes_pca, 'PCA-2')
            %             zlabel(app.UIAxes_pca, '3')
            
            app.log = ['Figures are updated.', app.log];
            app.logListBox.Items = app.log;
            drawnow
            
            app.UIAxes_hist.Title.Color = [0.90,0.90,0.90];
            app.UIAxes_pca.Title.Color = [0.90,0.90,0.90];
            app.UIAxes_waveform.Title.Color = [0.90,0.90,0.90];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isfile('config_detection')
                settings_detection_default();
            end
            app.UIAxes_hist.Title.Color = [0.90,0.90,0.90];
            app.UIAxes_pca.Title.Color = [0.90,0.90,0.90];
            app.UIAxes_waveform.Title.Color = [0.90,0.90,0.90];
            
            app.s_detection = read_arguments_from_json('config_detection');
            
            app.ThresholdMethodDropDown.Value = app.s_detection.thr_method;
            app.SignalSideThresholdingDropDown.Value = app.s_detection.side;
            app.SpikeFilterTypeDropDown.Value = app.s_detection.spike_filter_type;
            app.FilterOrderSpinner.Value = app.s_detection.spike_filter_order;
            app.PassFreqHzEditField.Value = app.s_detection.spike_Rp;
            app.StopFreqHzEditField.Value = app.s_detection.spike_Rs;
            app.PreThresholdsamplesEditField.Value = app.s_detection.pre_thresh;
            app.PostThresholdsamplesEditField.Value = app.s_detection.post_thresh;
            app.DeadtimesamplesEditField.Value = app.s_detection.dead_time;
            app.SamplingRateSpinner.Value = app.s_detection.sampling_rate;
            
            if ~isfile('config_sorting')
                app.s_ASorting = settings_sorting_default();
            end
            
            app.s_ASorting = read_arguments_from_json('config_sorting');
            
            app.MaxShiftSpinner.Value = app.s_ASorting.alignment.max_shift;
            app.NumberofPeaksSpinner.Value = app.s_ASorting.alignment.n_peaks;
            app.HistogrambinsSpinner.Value = app.s_ASorting.alignment.number_of_hist_bins;
            app.ComparisonmodeDropDown.Value = app.s_ASorting.alignment.comparison_mode;
            app.MaxstdEditField.Value = app.s_ASorting.stat_filter.max_std ;
            app.MaxmeanEditField.Value = app.s_ASorting.stat_filter.max_mean;
            app.MaxpercentofoutliersEditField.Value = app.s_ASorting.stat_filter.max_percent_of_outliers ;
            app.nuEditField.Value = app.s_ASorting.sort.nu ;
            app.gmaxSpinner.Value = app.s_ASorting.sort.g_max;
            app.gminSpinner.Value = app.s_ASorting.sort.g_min;
            app.MaxiterationSpinner.Value = app.s_ASorting.sort.max_iter;
            app.ulimEditField.Value = app.s_ASorting.sort.u_lim;
            app.NEditField.Value = app.s_ASorting.sort.N;
            %app.StatisticalFilteringCheckBox.Value = check_noise_remove;
            %app.AlignmentCheckBox.Value = check_align;
            app.PCAnumSpinner.Value =  app.s_ASorting.sort.n_pca;
            app.RandomseedEditField.Value = app.s_ASorting.sort.random_seed;
            app.SortingType.Value = app.s_ASorting.sort.type;
            if strcmpi(app.SortingType.Value,'skew-t dist')
                app.PCAnumSpinnerLabel.Text = 'PCA max';
                app.PCAnumSpinner.Value =  app.s_ASorting.sort.n_pca_max;
                app.ulimEditFieldLabel.Text = 'error';
                app.ulimEditField.Value = app.s_ASorting.sort.error;
            end
            
            app.log = {'Spike Sorter is started'};
            app.logListBox.Items = app.log;
            
        end

        % Callback function
        function ButtonPushed(app, event)
            %
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
        end

        % Button pushed function: StartDetectionButton
        function StartDetectionButtonPushed(app, event)
            if isempty(app.raw_data)
                %                 uialert(figure,'Choose a proper Raw Data. You can choose it from File > Open.', 'No Raw Data')
                msgbox('Choose a proper Raw Data. You can choose it from File > Open.', 'No Raw Data','error')
            else
                app.log = ['Starting spike detection...', app.log];
                app.logListBox.Items = app.log;
                drawnow
                
                app.s_detection.thr_method = app.ThresholdMethodDropDown.Value;
                app.s_detection.side = app.SignalSideThresholdingDropDown.Value;
                app.s_detection.spike_filter_type = app.SpikeFilterTypeDropDown.Value;
                app.s_detection.spike_filter_order = app.FilterOrderSpinner.Value;
                app.s_detection.spike_Rp = app.PassFreqHzEditField.Value;
                app.s_detection.spike_Rs = app.StopFreqHzEditField.Value;
                app.s_detection.pre_thresh = app.PreThresholdsamplesEditField.Value;
                app.s_detection.post_thresh = app.PostThresholdsamplesEditField.Value;
                app.s_detection.dead_time = app.DeadtimesamplesEditField.Value;
                app.s_detection.sampling_rate = app.SamplingRateSpinner.Value;
                
                [app.SpikeMat,app.SpikeTime] = spike_detection(app.raw_data, app.s_detection);
                
                app.update_histogram()
                if isempty(app.SpikeMat)
                    app.log = ['spike detection failed.', app.log];
                    app.logListBox.Items = app.log;
                else
                    app.log = ['spikes are detected.', app.log];
                    app.logListBox.Items = app.log;
                    
                    app.saveDetectionResultMenu.Enable = true;
                end
            end
        end

        % Button pushed function: SetasDefaultSettingButtonDetection
        function SetasDefaultSettingButtonDetectionPushed(app, event)
            app.s_detection.thr_method = app.ThresholdMethodDropDown.Value;
            app.s_detection.side = app.SignalSideThresholdingDropDown.Value;
            app.s_detection.spike_filter_type = app.SpikeFilterTypeDropDown.Value;
            app.s_detection.spike_filter_order = app.FilterOrderSpinner.Value;
            app.s_detection.spike_Rp = app.PassFreqHzEditField.Value;
            app.s_detection.spike_Rs = app.StopFreqHzEditField.Value;
            app.s_detection.pre_thresh = app.PreThresholdsamplesEditField.Value;
            app.s_detection.post_thresh = app.PostThresholdsamplesEditField.Value;
            app.s_detection.dead_time = app.DeadtimesamplesEditField.Value;
            app.s_detection.sampling_rate = app.SamplingRateSpinner.Value;
            
            write_arguments_to_json(app.s_detection,'config_detection');
            
            app.log = ['your Detection Setting is set as default setting.', app.log];
            app.logListBox.Items = app.log;
        end

        % Value changed function: logListBox
        function logListBoxValueChanged(app, event)
            %             value = app.logListBox.Value;
            
        end

        % Button pushed function: SetasdefaultsettingButtonSort
        function SetasdefaultsettingButtonSortPushed(app, event)
            app.s_ASorting.alignment.max_shift = app.MaxShiftSpinner.Value;
            app.s_ASorting.alignment.n_peaks = app.NumberofPeaksSpinner.Value;
            app.s_ASorting.alignment.number_of_hist_bins = app.HistogrambinsSpinner.Value;
            app.s_ASorting.alignment.comparison_mode = app.ComparisonmodeDropDown.Value;
            app.s_ASorting.stat_filter.max_std = app.MaxstdEditField.Value;
            app.s_ASorting.stat_filter.max_mean = app.MaxmeanEditField.Value;
            app.s_ASorting.stat_filter.max_percent_of_outliers = app.MaxpercentofoutliersEditField.Value;
            app.s_ASorting.sort.nu = app.nuEditField.Value;
            app.s_ASorting.sort.g_max = app.gmaxSpinner.Value;
            app.s_ASorting.sort.g_min = app.gminSpinner.Value;
            app.s_ASorting.sort.max_iter =app.MaxiterationSpinner.Value;
            app.s_ASorting.sort.u_lim = app.ulimEditField.Value;
            app.s_ASorting.sort.N = app.NEditField.Value;
            app.s_ASorting.sort.n_pca = app.PCAnumSpinner.Value;
            app.s_ASorting.sort.random_seed = app.RandomseedEditField.Value;
            app.s_ASorting.sort.n_pca_max = app.PCAnumSpinner.Value;
            app.s_ASorting.sort.error = app.ulimEditField.Value;
            app.s_ASorting.sort.type = app.SortingType.Value;
            
            write_arguments_to_json(app.s_ASorting,'config_sorting');
            
            app.log = ['your sorting Setting is set as default setting.', app.log];
            app.logListBox.Items = app.log;
        end

        % Value changed function: AlignmentCheckBox
        function AlignmentCheckBoxValueChanged(app, event)
            value = app.AlignmentCheckBox.Value;
            if ~value
                app.MaxShiftSpinner.Enable = false;
                app.NumberofPeaksSpinner.Enable = false;
                app.HistogrambinsSpinner.Enable = false;
                app.ComparisonmodeDropDown.Enable = false;
            else
                app.MaxShiftSpinner.Enable = true;
                app.NumberofPeaksSpinner.Enable = true;
                app.HistogrambinsSpinner.Enable = true;
                app.ComparisonmodeDropDown.Enable = true;
            end
            
        end

        % Value changed function: StatisticalFilteringCheckBox
        function StatisticalFilteringCheckBoxValueChanged(app, event)
            value = app.StatisticalFilteringCheckBox.Value;
            if ~value
                app.MaxstdEditField.Enable = false;
                app.MaxmeanEditField.Enable = false;
                app.MaxpercentofoutliersEditField.Enable = false;
                
            else
                app.MaxstdEditField.Enable = true;
                app.MaxmeanEditField.Enable = true;
                app.MaxpercentofoutliersEditField.Enable = true;
            end
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
            if ~isempty(app.SpikeMat)
                app.s_ASorting.alignment.max_shift = app.MaxShiftSpinner.Value;
                app.s_ASorting.alignment.n_peaks = app.NumberofPeaksSpinner.Value;
                app.s_ASorting.alignment.number_of_hist_bins = app.HistogrambinsSpinner.Value;
                app.s_ASorting.alignment.comparison_mode = app.ComparisonmodeDropDown.Value;
                app.s_ASorting.stat_filter.max_std = app.MaxstdEditField.Value;
                app.s_ASorting.stat_filter.max_mean = app.MaxmeanEditField.Value;
                app.s_ASorting.stat_filter.max_percent_of_outliers = app.MaxpercentofoutliersEditField.Value;
                app.s_ASorting.sort.nu = app.nuEditField.Value;
                app.s_ASorting.sort.g_max = app.gmaxSpinner.Value;
                app.s_ASorting.sort.g_min = app.gminSpinner.Value;
                app.s_ASorting.sort.max_iter =app.MaxiterationSpinner.Value;
                app.s_ASorting.sort.u_lim = app.ulimEditField.Value;
                app.s_ASorting.sort.N = app.NEditField.Value;
                app.s_ASorting.sort.n_pca = app.PCAnumSpinner.Value;
                app.s_ASorting.sort.random_seed = app.RandomseedEditField.Value;
                app.s_ASorting.sort.n_pca_max = app.PCAnumSpinner.Value;
                app.s_ASorting.sort.error = app.ulimEditField.Value;
                
                check_noise_remove = app.StatisticalFilteringCheckBox.Value;
                check_align = app.AlignmentCheckBox.Value;
                
                app.log = ['Starting Automatic sorting...', app.log];
                app.logListBox.Items = app.log;
                drawnow
                
                if check_align
                    app.SpikeMat = spike_alignment(app.SpikeMat,app.SpikeTime,app.s_ASorting);
                end
                
                REM = [];
                
                if check_noise_remove
                    REM = statistical_filter(app.SpikeMat,app.s_ASorting);
                end
                
                if strcmpi(app.SortingType.Value,'t dist')
                    app.os = RT_spike_sorter_faster(app.SpikeMat,app.s_ASorting,REM,false);
                else
                    app.os = RT_ST_spike_sorter(app.SpikeMat,app.s_ASorting,REM,false);
                end
                
                app.MSort_enable(true);
                
                app.update_figure()
                app.log = ['Automatic Sorting done!', app.log];
                app.logListBox.Items = app.log;
                
            else
                msgbox('There is no detection results', 'No Detection','error');
                app.MSort_enable(false);
                
            end
        end

        % Menu selected function: RawDataMenu
        function RawDataMenuSelected(app, event)
            if ~isempty(app.SpikeMat) || ~isempty(app.SpikeTime) || ~isempty(app.os)
                %                 f = uifigure;
                msg = 'If you load new data, your results will be lost. Do you want to save the results?';
                title = 'Save the results';
                selection = uiconfirm(app.UIFigure ,msg,title,'Options',{'Yes','No','Cancel'},'DefaultOption',1,'CancelOption',3);
                
                if isequal(selection,'No')
                    
                    app.load_raw()
                    
                elseif isequal(selection,'Yes')
                    if isempty(app.os.cluster_index)
                        app.saveDetectionResultMenuSelected();
                        app.load_raw()
                    else
                        app.saveSortingResultsMenuSelected();
                        app.load_raw()
                    end
                end
            else
                app.load_raw()
            end
        end

        % Menu selected function: saveDetectionResultMenu
        function saveDetectionResultMenuSelected(app, event)
            SpikeMat = app.SpikeMat;
            SpikeTime = app.SpikeTime;
            uisave({'SpikeMat', 'SpikeTime'},'spike.mat')
            drawnow; figure(app.UIFigure)
            
            app.log = ['Detection Results saved.', app.log];
            app.logListBox.Items = app.log;
        end

        % Menu selected function: DetectedDataMenu
        function DetectedDataMenuSelected(app, event)
            if ~isempty(app.SpikeMat) || ~isempty(app.SpikeTime) || ~isempty(app.os)
                %                 f = uifigure;
                msg = 'If you load new data, your results will be lost. Do you want to save the results?';
                title = 'Save the results';
                selection = uiconfirm(app.UIFigure ,msg,title,'Options',{'Yes','No','Cancel'},'DefaultOption',1,'CancelOption',3);
                
                if isequal(selection,'No')
                    app.load_detected()
                    
                elseif isequal(selection,'Yes')
                    if isempty(app.os)
                        app.saveDetectionResultMenuSelected();
                        app.load_detected()
                    else
                        app.saveSortingResultsMenuSelected();
                        app.load_detected()
                    end
                end
            else
                app.load_detected()
            end
            
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            selected_indices = app.ClustersindexListBox.Value;
            if length(selected_indices) > 1
                app.log = ['Start merging...', app.log];
                app.logListBox.Items = app.log;
                drawnow
                
                app.back_history(2:5,:) = app.back_history(1:4,:);
                app.back_history{1,1} = 'Merge';
                app.back_history{1,2} = app.os.cluster_index;
                
                selected_indices = cellfun(@str2double, selected_indices);
                % merging
                app.os.cluster_index(ismember(app.os.cluster_index, selected_indices)) = min(selected_indices);
                % cleaning cluster indices
                app.os.cluster_index = cluster_index_cleaner(app.os.cluster_index);
                app.ClustersindexListBox.Items = string(unique(app.os.cluster_index));
                
                app.update_figure()
                app.log = ['Merging Done!', app.log];
                app.logListBox.Items = app.log;
                
                
            else
                msgbox('You should select more than a cluster to merge.', 'Merge Error', 'Error')
            end
        end

        % Menu selected function: saveSortingResultsMenu
        function saveSortingResultsMenuSelected(app, event)
            
            SpikeMat = app.SpikeMat;
            SpikeTime = app.SpikeTime;
            optimal_set = app.os;
            if ~isempty(app.tag)
                tag = app.tag;
                uisave({'SpikeMat', 'SpikeTime', 'optimal_set', 'tag'},'sort_res.mat')
                drawnow; figure(app.UIFigure)
            else
                uisave({'SpikeMat', 'SpikeTime', 'optimal_set'},'sort_res.mat')
                drawnow; figure(app.UIFigure)
            end
            app.log = ['Sorting Results saved.', app.log];
            app.logListBox.Items = app.log;
        end

        % Menu selected function: SortedDataMenu
        function SortedDataMenuSelected(app, event)
            if ~isempty(app.SpikeMat) || ~isempty(app.SpikeTime) || ~isempty(app.os)
                %                 f = uifigure;
                msg = 'If you load new data, your results will be lost. Do you want to save the results?';
                title = 'Save the results';
                selection = uiconfirm(app.UIFigure ,msg,title,'Options',{'Yes','No','Cancel'},'DefaultOption',1,'CancelOption',3);
                
                if isequal(selection,'No')
                    app.load_sorted()
                    
                elseif isequal(selection,'Yes')
                    if isempty(app.os.cluster_index)
                        app.saveDetectionResultMenuSelected();
                        app.load_sorted()
                    else
                        app.saveSortingResultsMenuSelected();
                        app.load_sorted()
                    end
                end
            else
                app.load_sorted()
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            selected_indices = app.ClustersindexListBox.Value;
            if ~isempty(selected_indices)
                
                app.log = ['Start removig the cluster...', app.log];
                app.logListBox.Items = app.log;
                drawnow
                
                selected_indices = cellfun(@str2double, selected_indices);
                
                app.back_history(2:5,:) = app.back_history(1:4,:);
                app.back_history{1,1} = 'Remove';
                app.back_history{1,2} = {app.os.cluster_index(ismember(app.os.cluster_index, selected_indices)),...
                    app.SpikeMat(ismember(app.os.cluster_index, selected_indices),:),...
                    app.SpikeTime(ismember(app.os.cluster_index, selected_indices)), selected_indices};
                
                % removing selected clusters
                app.SpikeMat(ismember(app.os.cluster_index, selected_indices),:) = [];
                app.SpikeTime(ismember(app.os.cluster_index, selected_indices)) = [];
                app.os.cluster_index(ismember(app.os.cluster_index, selected_indices)) = [];
                
                % cleaning cluster indices
                app.os.cluster_index = cluster_index_cleaner(app.os.cluster_index);
                app.ClustersindexListBox.Items = string(unique(app.os.cluster_index));
                
                app.update_figure()
                app.log = ['Removing Done!', app.log];
                app.logListBox.Items = app.log;
            else
                msgbox('You should select a cluster to remove.', 'Remove Error', 'Error')
            end
        end

        % Button pushed function: ResortButton
        function ResortButtonPushed(app, event)
            selected_indices = app.ClustersindexListBox.Value;
            if ~isempty(selected_indices)
                app.log = ['Start resorting the cluster...', app.log];
                app.logListBox.Items = app.log;
                drawnow
                
                app.back_history(2:5,:) = app.back_history(1:4,:);
                app.back_history{1,1} = 'Resort';
                app.back_history{1,2} = app.os.cluster_index;
                
                selected_indices = cellfun(@str2double, selected_indices);
                max_cluster_index = max(app.os.cluster_index(app.os.cluster_index < 200));
                
                % considering not selected clusters as REM
                REM = ~ismember(app.os.cluster_index,selected_indices);
                
                % resorting
                os_new = RT_spike_sorter_faster(app.SpikeMat,app.s_ASorting ,REM,true);
                app.os.cluster_index(~REM) = os_new.cluster_index(~REM) + max_cluster_index;
                app.os.cluster_index = cluster_index_cleaner(app.os.cluster_index);
                app.ClustersindexListBox.Items = string(unique(app.os.cluster_index));
                
                app.update_figure()
                app.log = ['Resorting Done!', app.log];
                app.logListBox.Items = app.log;
            else
                msgbox('You should select a cluster to resort.', 'Resort Error', 'Error')
            end
        end

        % Button pushed function: AssigntonearestButton
        function AssigntonearestButtonPushed(app, event)
            app.DialogApp_assign = dialogbox_AssignToNearest(app);
            
            %             app.update_figure();
            
            drawnow
        end

        % Button pushed function: PCAremoveButton
        function PCAremoveButtonPushed(app, event)
            app.PCAremoveButton.Enable = false;
            fig_pca = figure('Name','PCA Remove','NumberTitle','off');
            [~,spike_pca] = pca(app.SpikeMat, 'NumComponents',2);
            histogram2( spike_pca(:,1), spike_pca(:,2), [100, 100], 'FaceColor','flat')
            grid on, view(2)
            title(sprintf('\nSelect the area you want to keep.\n The outter area will be removed as noise.\n'))
            xlabel('PCA1')
            ylabel('PCA2')
            h = drawfreehand();
            if isvalid(fig_pca)
                if ~isempty(h)
                    Pv = get(h,'Position');
                    close(fig_pca)
                    
                    % spikes inside selected region
                    in = inpolygon(spike_pca(:,1),spike_pca(:,2),Pv(:,1),Pv(:,2));
                    
                    clusters = unique(app.os.cluster_index);
                    app.back_history(2:5,:) = app.back_history(1:4,:);
                    app.back_history{1,1} = 'PCA remove';
                    app.back_history{1,2} = {app.os.cluster_index(~in), app.SpikeMat(~in,:), ...
                        app.SpikeTime(~in,:)};
                    
                    % removing outside the selected region
                    app.SpikeMat(~in,:) = [];
                    app.SpikeTime(~in,:) = [];
                    app.os.cluster_index(~in) = [];
                    app.os.cluster_index = cluster_index_cleaner(app.os.cluster_index);
                    
                    app.back_history{1,2} = [app.back_history{1,2}, {find(~ismember(clusters, app.os.cluster_index))}];
                    
                    app.ClustersindexListBox.Items = string(unique(app.os.cluster_index));
                    
                    app.update_histogram();
                    app.update_figure();
                    
                    app.log = ['PCA removing is Done!', app.log];
                    app.logListBox.Items = app.log;
                end
            end
            
            app.PCAremoveButton.Enable = true;


        end

        % Button pushed function: PCAgroupButton
        function PCAgroupButtonPushed(app, event)
            app.PCAgroupButton.Enable = false;
            fig_pca = figure('Name','PCA Group','NumberTitle','off');
            [~,spike_pca] = pca(app.SpikeMat, 'NumComponents',2);
            histogram2(spike_pca(:,1), spike_pca(:,2), [100, 100], 'FaceColor','flat')
            grid on, view(2)
            title(sprintf('\nSelect the area you want to group as a new cluster.\n'))
            xlabel('PCA1')
            ylabel('PCA2')
            h = drawfreehand();
            if isvalid(fig_pca)
                if ~isempty(h)
                    
                    app.back_history(2:5,:) = app.back_history(1:4,:);
                    app.back_history{1,1} = 'PCA group';
                    app.back_history{1,2} = app.os.cluster_index;
                    
                    Pv = get(h,'Position');
                    close(fig_pca)
                    % choose spikes inside selected area
                    in = inpolygon(spike_pca(:,1),spike_pca(:,2),Pv(:,1),Pv(:,2));
                    max_index = max(app.os.cluster_index(app.os.cluster_index<200));
                    
                    % assign to new cluster
                    app.os.cluster_index(in) = max_index + 1;
                    app.os.cluster_index = cluster_index_cleaner(app.os.cluster_index);
                    app.ClustersindexListBox.Items = string(unique(app.os.cluster_index));
                    
                    app.update_histogram();
                    app.update_figure();
                    
                    app.log = ['PCA group is Done!', app.log];
                    app.logListBox.Items = app.log;
                    
                end
            end
            
            app.PCAgroupButton.Enable = true;
            
        end

        % Button pushed function: TagButton
        function TagButtonPushed(app, event)
            app.DialogApp_tag = dialogbox_tag(app);
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if isempty(app.back_history{1,1}) || isequal(app.back_history{1,1},'start')
                msgbox('There is no more history to undo.', 'No back', 'Error')
            else
                switch app.back_history{1,1}
                    case 'Merge'
                        app.os.cluster_index = app.back_history{1,2};
                        
                    case 'Remove'
                        for i = [app.back_history{1,2}{4}]
                            app.os.cluster_index(app.os.cluster_index >= i) = app.os.cluster_index(app.os.cluster_index >= i) + 1;
                        end
                        app.os.cluster_index = [app.os.cluster_index ; app.back_history{1,2}{1}];
                        app.SpikeMat = [app.SpikeMat; app.back_history{1,2}{2}];
                        app.SpikeTime = [app.SpikeTime; app.back_history{1,2}{3}];
                        
                    case 'Resort'
                        app.os.cluster_index = app.back_history{1,2};
                        
                    case 'denoise'
                        
                        
                    case 'Assign to nearest'
                        app.os.cluster_index = app.back_history{1,2};
                        
                    case 'PCA remove'
                        for i = [app.back_history{1,2}{4}]
                            if ~isempty(i)
                                app.os.cluster_index(app.os.cluster_index >= i) = app.os.cluster_index(app.os.cluster_index >= i) + 1;
                            end
                        end
                        app.os.cluster_index = [app.os.cluster_index ; app.back_history{1,2}{1}];
                        app.SpikeMat = [app.SpikeMat; app.back_history{1,2}{2}];
                        app.SpikeTime = [app.SpikeTime; app.back_history{1,2}{3}];
                        
                    case 'PCA group'
                        app.os.cluster_index = app.back_history{1,2};
                    case 'Denoise'
                        app.os.cluster_index = app.back_history{1,2};
                end
                
                app.update_histogram()
                app.update_figure()
                app.ClustersindexListBox.Items = string(unique(app.os.cluster_index));
                app.log = [join(['Undo: ',app.back_history{1,1}]), app.log];
                app.logListBox.Items = app.log;
                
                app.back_history(1:4,:) = app.back_history(2:5,:);
                app.back_history(5,:) ={[]} ;
                
                
            end
        end

        % Menu selected function: PlotMenu
        function PlotMenuSelected(app, event)
            try
                
                os = app.os;
                SpikeMat = app.SpikeMat;
                time_duration = app.SpikeTime(end) - app.SpikeTime(1);
                figure('Name','Waveforms','NumberTitle','off','units','normalized','outerposition',[0 0 1 1])
                % cluster names
                classes = unique(os.cluster_index);
                % considering three subplots in each row, j is row number and i is column number.
                for j = 1 : ceil(length(classes)/3)
                    for i = 1 : 3
                        if (j-1)*3+i > length(classes)
                            break
                        end
                        cluster_spikes = SpikeMat(os.cluster_index == classes((j-1)*3+i),:);
                        mean_spike = mean(cluster_spikes);
                        % n is the limit for number of plotted spikes for each cluster
                        n = 1000;
                        if length(classes) > 10
                            n = 100;
                        end
                        if size(cluster_spikes,1) > n
                            ii = randperm(size(cluster_spikes,1),n);
                            cluster_spikes = cluster_spikes(ii,:);
                        end
                        subplot(ceil(length(classes)/3),3,(j-1)*3+i)
                        plot(cluster_spikes','Color',app.color((j-1)*3+i,:))
                        hold on
                        plot(mean_spike,'LineWidth',2)
                        if ~ismember(classes((j-1)*3+i),[254,255])
                            ylim([-10,10])
                        end
                        n_cluster = sum(os.cluster_index == classes((j-1)*3+i));
                        title(sprintf('SP = %d, PoP=%.1f, FR=%f',...
                            n_cluster,n_cluster./length(os.cluster_index)*100,...
                            n_cluster/time_duration*40000));
                        xlabel(sprintf('C_i = %d',classes((j-1)*3+i)))
                    end
                end
            end
        end

        % Menu selected function: LiveTimeMenu
        function LiveTimeMenuSelected(app, event)
            try
                os = app.os;
                SpikeTime = app.SpikeTime;
                figure('Name','Live Time','NumberTitle','off')
                classes = unique(os.cluster_index);
                % considering three columns in each row. i is column number and j is row number.
                for j = 1 : ceil(length(classes)/3)
                    for i = 1 : 3
                        if (j-1)*3+i > length(classes)
                            break
                        end
                        % spike times of each cluster
                        cluster_times = SpikeTime(os.cluster_index == classes((j-1)*3+i));
                        subplot(ceil(length(classes)/3),3,(j-1)*3+i)
                        % plotting histogram of cluster times
                        histogram(cluster_times,100,'FaceColor',app.color((j-1)*3+i,:))
                        title(sprintf('cluster=%d',classes((j-1)*3+i)))
                        xlim([0,SpikeTime(end)])
                        if classes((j-1)*3+i) == 255
                            xlabel('OUTLIERS')
                        end
                    end
                end
            end
            
        end

        % Menu selected function: ISIMenu
        function ISIMenuSelected(app, event)
            try
                os = app.os;
                SpikeTime= app.SpikeTime;
                figure('Name','Inter Spike Interval','NumberTitle','off')
                classes = unique(os.cluster_index);
                % considering three subplots in each row, j is row number and i is column number.
                for j = 1 : ceil(length(classes)/3)
                    for i = 1 : 3
                        if (j-1)*3+i > length(classes)
                            break
                        end
                        % spike times of each cluster
                        cluster_times = SpikeTime(os.cluster_index == classes((j-1)*3+i));
                        % calculating inter-spike intervals
                        cluster_isi = diff(sort(cluster_times));
                        subplot(ceil(length(classes)/3),3,(j-1)*3+i)
                        %             [count,center] = hist(cluster_isi,floor(length(cluster_isi)/10));
                        % histogram of ISIs
                        [count,center] = hist(cluster_isi,1000);
                        % fitting gamma distribution
                        pd = fitdist(cluster_isi,'gamma');
                        count = count ./max(count);
                        center(count<0.001) = [];
                        count(count<0.001) = [];
                        bar(center,count,'FaceColor',app.color((j-1)*3+i,:))
                        hold on
                        p = pdf(pd,center);
                        p = p./max(p);
                        plot(center,p,'LineWidth',3)
                        title(sprintf('c_i=%d',classes((j-1)*3+i)))
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.SpikeMat) || ~isempty(app.SpikeTime) || ~isempty(app.os)
                msg = 'You are about to close Spike Sorter, your results will be lost. Do you want to save the results?';
                title = 'Closing';
                selection = uiconfirm(app.UIFigure ,msg,title,'Options',{'Yes','No','Cancel'},'DefaultOption',1,'CancelOption',3);
                
                if isequal(selection,'No')
                    delete(app)
                elseif isequal(selection, 'Yes')
                    if isempty(app.os.cluster_index)
                        app.saveDetectionResultMenuSelected();
                        app.os = [];
                        app.SpikeMat = [];
                        app.SpikeTime = [];
                    else
                        app.saveSortingResultsMenuSelected();
                        app.SpikeMat = [];
                        app.SpikeTime = [];
                    end
                    delete(app)
                end
            else
                
                msg = 'You are about to close Spike Sorter. Are you sure?';
                title = 'Closing';
                selection = uiconfirm(app.UIFigure ,msg,title,'Options',{'Yes','Cancel'},'DefaultOption',2,'CancelOption',2);
                if isequal(selection,'Yes')
                    delete(app)
                end
            end
        end

        % Menu selected function: DPlotMenu
        function DPlotMenuSelected(app, event)
            app.DialogApp_3Dplot = dialogbox_3Dplot(app);
        end

        % Button pushed function: DenoiseButton
        function DenoiseButtonPushed(app, event)
            app.DialogApp_denoise = dialogbox_denoise(app);
            
            
        end

        % Menu selected function: RawDataMenu_plot
        function RawDataMenu_plotSelected(app, event)
            if ~isempty(app.raw_data)
                
                fig_raw = figure('Name', 'Raw Data');
                ftype = app.SpikeFilterTypeDropDown.Value;
                forder = app.FilterOrderSpinner.Value;
                fRp= app.PassFreqHzEditField.Value;
                fRs = app.StopFreqHzEditField.Value;
                fR = app.SamplingRateSpinner.Value;
                pre_thresh = app.PreThresholdsamplesEditField.Value;
                post_thresh = app.PostThresholdsamplesEditField.Value;
                if app.AlignmentCheckBox.Enable
                    max_shift = app.MaxShiftSpinner.Value;
                else
                    max_shift = 0;
                end
                
                % butterworth filter coefficients
                [b,a]= feval(ftype,forder,[fRp,fRs]/(fR/2));
                
                % filtering
                spike_raw = filtfilt(b,a,app.raw_data);
                % plotting raw data
                LinePlotReducer(1:length(spike_raw), spike_raw,'Color',[0.8,0.8,0.8]);
                
                % showing detected spikes on plotted raw data
                if ~isempty(app.SpikeMat) || ~isempty(app.SpikeTime)
                    hold(gca, 'on')
                    
                    
                    spike_time = repmat(app.SpikeTime,1,size(app.SpikeMat,2));
                    tmp = -pre_thresh+max_shift : post_thresh-max_shift;
                    if length(tmp) ~= size(app.SpikeMat,2)
                        tmp = -pre_thresh : post_thresh;
                    end
                    spike_time = spike_time + repmat(tmp, size(app.SpikeMat,1),1);
                    spike_time = spike_time';
                    %                     spike_time = [spike_time;NaN(1,size(spike_time,2))];
                    
                    spike_mat = app.SpikeMat';
                    %                     spike_mat = [spike_mat;NaN(1,size(spike_mat,2))];
                    
                    % if there exist sorting reslult, show each spike with
                    % its cluster color
                    if ~isempty(app.os)
                        classes = unique(app.os.cluster_index);
                        
                        for i= 1:length(classes)
                            ind = app.os.cluster_index == classes(i);
                            time_tmp = spike_time(:,ind);
                            mat_tmp = spike_mat(:,ind);
                            LinePlotReducer(time_tmp(:), mat_tmp(:),'--','Color', app.color(i,:));
                            
                        end
                    else
                        LinePlotReducer(spike_time(:), spike_mat(:),'--','Color','b');
                    end
                    title(sprintf('\nThis plot shows the detected spikes on the raw data. \nZoom on the plot for more details.\n'))
                end
                
            else
                msgbox('There is no Raw data. Please load a raw data first.', 'No Raw Data', 'Error');
            end
        end

        % Button pushed function: UpdateFiguresButton
        function UpdateFiguresButtonPushed(app, event)
            if ~isempty(app.SpikeMat)
                app.update_histogram()
                if ~isempty(app.os)
                    app.update_figure()
                end
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox(sprintf('Spike Sorter version 1.0.0\nSecure Communication Lab, University Of Tehran\nRamin Toosi, Morvarid Aryan'),'About Spike Sorter', 'help')
        end

        % Value changed function: SortingType
        function SortingTypeValueChanged(app, event)
            value = app.SortingType.Value;
            if strcmpi(value,'skew-t dist')
                app.PCAnumSpinnerLabel.Text = 'PCA max';
                app.ulimEditFieldLabel.Text = 'error';
                app.NEditField.Enable = false;
            else
                app.PCAnumSpinnerLabel.Text = 'PCA num';
                app.ulimEditFieldLabel.Text = 'u lim';
                app.NEditField.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Spike Sorter';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.RawDataMenu = uimenu(app.OpenMenu);
            app.RawDataMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDataMenuSelected, true);
            app.RawDataMenu.Text = 'Raw Data';
            app.DetectedDataMenu = uimenu(app.OpenMenu);
            app.DetectedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @DetectedDataMenuSelected, true);
            app.DetectedDataMenu.Text = 'Detected Data';
            app.SortedDataMenu = uimenu(app.OpenMenu);
            app.SortedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SortedDataMenuSelected, true);
            app.SortedDataMenu.Text = 'Sorted Data';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.saveDetectionResultMenu = uimenu(app.SaveMenu);
            app.saveDetectionResultMenu.MenuSelectedFcn = createCallbackFcn(app, @saveDetectionResultMenuSelected, true);
            app.saveDetectionResultMenu.Enable = 'off';
            app.saveDetectionResultMenu.Text = 'Detection Result';
            app.saveSortingResultsMenu = uimenu(app.SaveMenu);
            app.saveSortingResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @saveSortingResultsMenuSelected, true);
            app.saveSortingResultsMenu.Enable = 'off';
            app.saveSortingResultsMenu.Text = 'Sorting Results';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.VisualizationMenu = uimenu(app.UIFigure);
            app.VisualizationMenu.Text = 'Visualization';
            app.PlotMenu = uimenu(app.VisualizationMenu);
            app.PlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotMenuSelected, true);
            app.PlotMenu.Enable = 'off';
            app.PlotMenu.Text = 'Plot';
            app.LiveTimeMenu = uimenu(app.VisualizationMenu);
            app.LiveTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @LiveTimeMenuSelected, true);
            app.LiveTimeMenu.Enable = 'off';
            app.LiveTimeMenu.Text = 'Live Time';
            app.ISIMenu = uimenu(app.VisualizationMenu);
            app.ISIMenu.MenuSelectedFcn = createCallbackFcn(app, @ISIMenuSelected, true);
            app.ISIMenu.Enable = 'off';
            app.ISIMenu.Text = 'ISI';
            app.DPlotMenu = uimenu(app.VisualizationMenu);
            app.DPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @DPlotMenuSelected, true);
            app.DPlotMenu.Enable = 'off';
            app.DPlotMenu.Text = '3D Plot';
            app.RawDataMenu_plot = uimenu(app.VisualizationMenu);
            app.RawDataMenu_plot.MenuSelectedFcn = createCallbackFcn(app, @RawDataMenu_plotSelected, true);
            app.RawDataMenu_plot.Enable = 'off';
            app.RawDataMenu_plot.Text = 'Raw Data';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.StartDetectionButton = uibutton(app.DetectionTab, 'push');
            app.StartDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartDetectionButtonPushed, true);
            app.StartDetectionButton.Text = 'Start Detection';
            app.ThresholdMethodDropDownLabel = uilabel(app.DetectionTab);
            app.ThresholdMethodDropDownLabel.Text = {'Threshold'; 'Method'};
            app.ThresholdMethodDropDown = uidropdown(app.DetectionTab);
            app.ThresholdMethodDropDown.Items = {'median', 'wavelet', 'plexon'};
            app.ThresholdMethodDropDown.Value = 'median';
            app.SignalSideThresholdingDropDownLabel = uilabel(app.DetectionTab);
            app.SignalSideThresholdingDropDownLabel.Text = {'Signal Side'; 'Thresholding'};
            app.SignalSideThresholdingDropDown = uidropdown(app.DetectionTab);
            app.SignalSideThresholdingDropDown.Items = {'two', 'positive', 'negative', 'mean'};
            app.SignalSideThresholdingDropDown.Value = 'two';
            app.SpikerFilterTypeDropDownLabel = uilabel(app.DetectionTab);
            app.SpikerFilterTypeDropDownLabel.Text = {'Spiker Filter'; 'Type'};
            app.SpikeFilterTypeDropDown = uidropdown(app.DetectionTab);
            app.SpikeFilterTypeDropDown.Items = {'butter'};
            app.SpikeFilterTypeDropDown.Value = 'butter';
            app.FilterOrderSpinnerLabel = uilabel(app.DetectionTab);
            app.FilterOrderSpinnerLabel.Text = 'Filter Order';
            app.FilterOrderSpinner = uispinner(app.DetectionTab);
            app.FilterOrderSpinner.Limits = [1 50];
            app.FilterOrderSpinner.Tooltip = {'Higher order filters could result in detection failure'};
            app.FilterOrderSpinner.Value = 4;
            app.PassFreqHzLabel = uilabel(app.DetectionTab);
            app.PassFreqHzLabel.Text = {'Pass Freq'; '(Hz)    '};
            app.PassFreqHzEditField = uieditfield(app.DetectionTab, 'numeric');
            app.PassFreqHzEditField.Limits = [0 Inf];
            app.StopFreqHzLabel = uilabel(app.DetectionTab);
            app.StopFreqHzLabel.Text = {'Stop Freq'; '(Hz)   '};
            app.StopFreqHzEditField = uieditfield(app.DetectionTab, 'numeric');
            app.StopFreqHzEditField.Limits = [0 Inf];
            app.PreThresholdsamplesLabel = uilabel(app.DetectionTab);
            app.PreThresholdsamplesLabel.Text = {'Pre-Threshold'; '(samples)   '};
            app.PreThresholdsamplesEditField = uieditfield(app.DetectionTab, 'numeric');
            app.PreThresholdsamplesEditField.Limits = [0 Inf];
            app.PostThresholdsamplesLabel = uilabel(app.DetectionTab);
            app.PostThresholdsamplesLabel.Text = {'Post-Threshold'; '(samples)   '};
            app.PostThresholdsamplesEditField = uieditfield(app.DetectionTab, 'numeric');
            app.PostThresholdsamplesEditField.Limits = [0 Inf];
            app.DeadtimesamplesLabel = uilabel(app.DetectionTab);
            app.DeadtimesamplesLabel.Text = {'Dead time'; '(samples) '};
            app.DeadtimesamplesEditField = uieditfield(app.DetectionTab, 'numeric');
            app.DeadtimesamplesEditField.Limits = [1 Inf];
            app.DeadtimesamplesEditField.Value = 20;
            app.SetasDefaultSettingButtonDetection = uibutton(app.DetectionTab, 'push');
            app.SetasDefaultSettingButtonDetection.ButtonPushedFcn = createCallbackFcn(app, @SetasDefaultSettingButtonDetectionPushed, true);
            app.SetasDefaultSettingButtonDetection.Text = 'Set as Default Setting';
            app.SamplingRateLabel = uilabel(app.DetectionTab);
            app.SamplingRateLabel.Text = {'Sampling'; ' Rate'};
            app.SamplingRateSpinner = uispinner(app.DetectionTab);
            app.SamplingRateSpinner.Limits = [0 Inf];
            app.SamplingRateSpinner.Value = 40000;
            app.AutoSortingTab = uitab(app.TabGroup);
            app.AutoSortingTab.Title = 'Auto-Sorting';
            app.AlignmentLabel = uilabel(app.AutoSortingTab);
            app.AlignmentLabel.Text = 'Alignment';
            app.tDistributionSortingLabel = uilabel(app.AutoSortingTab);
            app.tDistributionSortingLabel.Text = 't-Distribution Sorting';
            app.StatisticalFilteringLabel = uilabel(app.AutoSortingTab);
            app.StatisticalFilteringLabel.Text = 'Statistical Filtering';
            app.MaxShiftSpinnerLabel = uilabel(app.AutoSortingTab);
            app.MaxShiftSpinnerLabel.Text = 'Max Shift';
            app.MaxShiftSpinner = uispinner(app.AutoSortingTab);
            app.MaxShiftSpinner.Limits = [1 Inf];
            app.MaxShiftSpinner.Value = 1;
            app.NumberofPeaksLabel = uilabel(app.AutoSortingTab);
            app.NumberofPeaksLabel.Text = {'Number'; ' of Peaks'};
            app.NumberofPeaksSpinner = uispinner(app.AutoSortingTab);
            app.NumberofPeaksSpinner.Limits = [1 Inf];
            app.NumberofPeaksSpinner.Value = 1;
            app.HistogrambinsSpinnerLabel = uilabel(app.AutoSortingTab);
            app.HistogrambinsSpinnerLabel.Text = {'Histogram'; ' bins '};
            app.HistogrambinsSpinner = uispinner(app.AutoSortingTab);
            app.HistogrambinsSpinner.Limits = [2 Inf];
            app.HistogrambinsSpinner.Value = 75;
            app.ComparisonmodeLabel = uilabel(app.AutoSortingTab);
            app.ComparisonmodeLabel.Text = {'Comparison'; ' mode'};
            app.ComparisonmodeDropDown = uidropdown(app.AutoSortingTab);
            app.ComparisonmodeDropDown.Items = {'index', 'magnitude'};
            app.ComparisonmodeDropDown.Value = 'magnitude';
            app.MaxstdEditFieldLabel = uilabel(app.AutoSortingTab);
            app.MaxstdEditFieldLabel.Text = 'Max std';
            app.MaxstdEditField = uieditfield(app.AutoSortingTab, 'numeric');
            app.MaxstdEditField.Limits = [1e-21 Inf];
            app.MaxstdEditField.Value = 0.01;
            app.MaxmeanEditFieldLabel = uilabel(app.AutoSortingTab);
            app.MaxmeanEditFieldLabel.Text = 'Max mean';
            app.MaxmeanEditField = uieditfield(app.AutoSortingTab, 'numeric');
            app.MaxmeanEditField.Limits = [1e-28 Inf];
            app.MaxmeanEditField.Value = 0.02;
            app.MaxpercentofoutliersLabel = uilabel(app.AutoSortingTab);
            app.MaxpercentofoutliersLabel.Text = {'Max percent'; ' of outliers'};
            app.MaxpercentofoutliersEditField = uieditfield(app.AutoSortingTab, 'numeric');
            app.nuEditFieldLabel = uilabel(app.AutoSortingTab);
            app.nuEditFieldLabel.Text = 'nu';
            app.nuEditField = uieditfield(app.AutoSortingTab, 'numeric');
            app.nuEditField.Limits = [0 Inf];
            app.nuEditField.Value = 20;
            app.gmaxSpinnerLabel = uilabel(app.AutoSortingTab);
            app.gmaxSpinnerLabel.Text = 'g max';
            app.gmaxSpinner = uispinner(app.AutoSortingTab);
            app.gmaxSpinner.Limits = [1 Inf];
            app.gmaxSpinner.Value = 9;
            app.gminSpinnerLabel = uilabel(app.AutoSortingTab);
            app.gminSpinnerLabel.Text = 'g min';
            app.gminSpinner = uispinner(app.AutoSortingTab);
            app.gminSpinner.Limits = [1 Inf];
            app.gminSpinner.Value = 1;
            app.MaxiterationSpinnerLabel = uilabel(app.AutoSortingTab);
            app.MaxiterationSpinnerLabel.Text = {'Max'; 'iteration'};
            app.MaxiterationSpinner = uispinner(app.AutoSortingTab);
            app.MaxiterationSpinner.Limits = [1 Inf];
            app.MaxiterationSpinner.Value = 500;
            app.ulimEditFieldLabel = uilabel(app.AutoSortingTab);
            app.ulimEditFieldLabel.Text = 'u lim';
            app.ulimEditField = uieditfield(app.AutoSortingTab, 'numeric');
            app.ulimEditField.Limits = [0 1];
            app.ulimEditField.Value = 0.01;
            app.NEditFieldLabel = uilabel(app.AutoSortingTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.AutoSortingTab, 'numeric');
            app.NEditField.Limits = [0 Inf];
            app.NEditField.Value = 15;
            app.StatisticalFilteringCheckBox = uicheckbox(app.AutoSortingTab);
            app.StatisticalFilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @StatisticalFilteringCheckBoxValueChanged, true);
            app.StatisticalFilteringCheckBox.Text = 'Statistical Filtering';
            app.StatisticalFilteringCheckBox.Value = true;
            app.AlignmentCheckBox = uicheckbox(app.AutoSortingTab);
            app.AlignmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlignmentCheckBoxValueChanged, true);
            app.AlignmentCheckBox.Text = 'Alignment';
            app.AlignmentCheckBox.Value = true;
            app.PCAnumSpinnerLabel = uilabel(app.AutoSortingTab);
            app.PCAnumSpinnerLabel.Text = 'PCA num';
            app.PCAnumSpinner = uispinner(app.AutoSortingTab);
            app.PCAnumSpinner.Limits = [1 Inf];
            app.PCAnumSpinner.Value = 5;
            app.RandomseedEditFieldLabel = uilabel(app.AutoSortingTab);
            app.RandomseedEditFieldLabel.Text = 'Random seed';
            app.RandomseedEditField = uieditfield(app.AutoSortingTab, 'numeric');
            app.RandomseedEditField.Value = 561371;
            app.SetasdefaultsettingButtonSort = uibutton(app.AutoSortingTab, 'push');
            app.SetasdefaultsettingButtonSort.ButtonPushedFcn = createCallbackFcn(app, @SetasdefaultsettingButtonSortPushed, true);
            app.SetasdefaultsettingButtonSort.Text = 'Set as default setting';
            app.SortButton = uibutton(app.AutoSortingTab, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.RunLabel = uilabel(app.AutoSortingTab);
            app.RunLabel.Text = 'Run';
            app.AlignmentLabel_2 = uilabel(app.AutoSortingTab);
            app.AlignmentLabel_2.Text = 'Alignment';
            app.SortingTypeLabel = uilabel(app.AutoSortingTab);
            app.SortingTypeLabel.Text = 'Sorting Type';
            app.SortingType = uidropdown(app.AutoSortingTab);
            app.SortingType.Items = {'t dist', 'skew-t dist'};
            app.SortingType.ValueChangedFcn = createCallbackFcn(app, @SortingTypeValueChanged, true);
            app.SortingType.Value = 't dist';
            app.ManualSortingTab = uitab(app.TabGroup);
            app.ManualSortingTab.Title = 'Manual Sorting';
            app.ClustersindexListBoxLabel = uilabel(app.ManualSortingTab);
            app.ClustersindexListBoxLabel.Text = 'Clusters index';
            app.ClustersindexListBox = uilistbox(app.ManualSortingTab);
            app.ClustersindexListBox.Items = {' '};
            app.ClustersindexListBox.Multiselect = 'on';
            app.ClustersindexListBox.Tooltip = {'You can select multiple clusters using CTRL or Shift. 254 is noise cluster.'};
            app.ClustersindexListBox.Value = {' '};
            app.MergeButton = uibutton(app.ManualSortingTab, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Enable = 'off';
            app.MergeButton.Tooltip = {'Merge the selected cluster indices'; ' from the left list.'};
            app.MergeButton.Text = 'Merge';
            app.RemoveButton = uibutton(app.ManualSortingTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Tooltip = {'Remove the selected cluster indices'; ' from the left list.'};
            app.RemoveButton.Text = 'Remove';
            app.ResortButton = uibutton(app.ManualSortingTab, 'push');
            app.ResortButton.ButtonPushedFcn = createCallbackFcn(app, @ResortButtonPushed, true);
            app.ResortButton.Enable = 'off';
            app.ResortButton.Tooltip = {'Resort the selected cluster indices'; ' from the left list.'};
            app.ResortButton.Text = 'Resort';
            app.DenoiseButton = uibutton(app.ManualSortingTab, 'push');
            app.DenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @DenoiseButtonPushed, true);
            app.DenoiseButton.Enable = 'off';
            app.DenoiseButton.Tooltip = {'Opens "Denoise" app.'};
            app.DenoiseButton.Text = 'Denoise';
            app.AssigntonearestButton = uibutton(app.ManualSortingTab, 'push');
            app.AssigntonearestButton.ButtonPushedFcn = createCallbackFcn(app, @AssigntonearestButtonPushed, true);
            app.AssigntonearestButton.Enable = 'off';
            app.AssigntonearestButton.Tooltip = {'Opens "Assign to Nearest" app.'};
            app.AssigntonearestButton.Text = 'Assign to nearest';
            app.TagButton = uibutton(app.ManualSortingTab, 'push');
            app.TagButton.ButtonPushedFcn = createCallbackFcn(app, @TagButtonPushed, true);
            app.TagButton.Enable = 'off';
            app.TagButton.Tooltip = {'Opens "Tag" app.'};
            app.TagButton.Text = 'Tag';
            app.PCAremoveButton = uibutton(app.ManualSortingTab, 'push');
            app.PCAremoveButton.ButtonPushedFcn = createCallbackFcn(app, @PCAremoveButtonPushed, true);
            app.PCAremoveButton.Enable = 'off';
            app.PCAremoveButton.Tooltip = {'Opens "PCA remove" app.'};
            app.PCAremoveButton.Text = 'PCA remove';
            app.PCAgroupButton = uibutton(app.ManualSortingTab, 'push');
            app.PCAgroupButton.ButtonPushedFcn = createCallbackFcn(app, @PCAgroupButtonPushed, true);
            app.PCAgroupButton.Enable = 'off';
            app.PCAgroupButton.Tooltip = {'Opens "PCA group" app.'};
            app.PCAgroupButton.Text = 'PCA group';
            app.BackButton = uibutton(app.ManualSortingTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Enable = 'off';
            app.BackButton.Tooltip = {'Undo. 5 actions permitted'};
            app.BackButton.Text = 'Back';
            app.UIAxes_hist = uiaxes(app.UIFigure);
            title(app.UIAxes_hist, 'Histogram')
            app.UIAxes_pca = uiaxes(app.UIFigure);
            title(app.UIAxes_pca, 'PCA')
            app.UIAxes_waveform = uiaxes(app.UIFigure);
            title(app.UIAxes_waveform, 'Waveform')
            app.logListBoxLabel = uilabel(app.UIFigure);
            app.logListBoxLabel.Text = 'log';
            app.logListBox = uilistbox(app.UIFigure);
            app.logListBox.Items = {};
            app.logListBox.ValueChangedFcn = createCallbackFcn(app, @logListBoxValueChanged, true);
            app.logListBox.Tooltip = {'log history'};
            app.logListBox.Value = {};
            app.UIAxes_legend = uiaxes(app.UIFigure);
            title(app.UIAxes_legend, 'Legend')
            app.UIAxes_legend.XTick = [];
            app.UIAxes_legend.YTick = [];
            app.CurrentFileLabel = uilabel(app.UIFigure);
            app.CurrentFileLabel.Text = 'Current File:';
            app.CurrentFileResultLabel = uilabel(app.UIFigure);
            app.CurrentFileResultLabel.Text = ' ';
            app.UpdateFiguresButton = uibutton(app.UIFigure, 'push');
            app.UpdateFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFiguresButtonPushed, true);
            app.UpdateFiguresButton.Text = 'Update Figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end